classdef Main_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        StartButton                    matlab.ui.control.Button
        byOoiWeiHongTP065329LeeJunWeiTP072623Label  matlab.ui.control.Label
        LicensePlateRecognitionStateIdentificationSystemLabel  matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        HTML                           matlab.ui.control.HTML
        Tab2                           matlab.ui.container.Tab
        BoundingBoxSwitch              matlab.ui.control.Switch
        BoundingBoxSwitchLabel         matlab.ui.control.Label
        VehicleDropDown                matlab.ui.control.DropDown
        VehicleDropDownLabel           matlab.ui.control.Label
        VehicleTypeDropDown            matlab.ui.control.DropDown
        VehicleTypeDropDownLabel       matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        OriginalImagePanel             matlab.ui.container.Panel
        OriginalImageUIAxes            matlab.ui.control.UIAxes
        StateIdentifiedEditFieldLabel  matlab.ui.control.Label
        PlateNumberLabel               matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        StateIdentifiedEditField       matlab.ui.control.EditField
        PlateNumberEditField           matlab.ui.control.EditField
        BackButton                     matlab.ui.control.Button
        SelectedPlateUIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
            originalImage
            plateRegion
            plateBbox
            currentFilename
            model
            detectionFigureHandle = []
            ocrFigureHandle = []
    end
    
    methods (Access = private)
        function showBoundingBox(app)
                % Ensure originalImage and plateBbox are available
            if isempty(app.originalImage) || isempty(app.plateBbox)
                return; % Nothing to do
            end

            % Create a copy of the original image
            imgWithBox = app.originalImage;
            
            % Draw the bounding box
            imgWithBox = insertShape(imgWithBox, 'Rectangle', app.plateBbox, ...
                                    'LineWidth', 3, 'Color', 'red');
            
            % Display the image with bounding box
            imshow(imgWithBox, 'Parent', app.OriginalImageUIAxes);
            
            % Set a descriptive title
            baseFilename = '';
            if ~isempty(app.currentFilename)
                baseFilename = regexprep(app.currentFilename, '\..*$', '');
                title(app.OriginalImageUIAxes, [baseFilename, ' (with Bounding Box)']);
            else
                title(app.OriginalImageUIAxes, 'Image with Bounding Box');
            end
        end
        
        function results = centerAppWindow(app)
            movegui(app.UIFigure, 'center');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pathToMLAPP = fileparts(mfilename('fullpath'));
    
            % Set the HTML source using a relative path
            app.HTML.HTMLSource = fullfile(pathToMLAPP, 'assets', 'Background.html');
            
            centerAppWindow(app);
            title(app.OriginalImageUIAxes, 'Title');
            title(app.SelectedPlateUIAxes, 'Selected Plate');
            app.VehicleTypeDropDown.Value = app.VehicleTypeDropDown.Items{1};
            app.VehicleDropDown.Value = app.VehicleDropDown.Items{1};
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.ResetButtonPushed([]);
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
                if isempty(app.originalImage)
                    errordlg('Please load an image first by selecting a vehicle type and then a vehicle.', 'Error');
                    return;
                end
                
                if strcmp(app.VehicleTypeDropDown.Value, 'Select Vehicle Type')
                    errordlg('Please select a vehicle type.', 'Error');
                    return;
                end
                
                if strcmp(app.VehicleDropDown.Value, 'Select Vehicle') || ...
                   strcmp(app.VehicleDropDown.Value, 'No images in folder') || ...
                   strcmp(app.VehicleDropDown.Value, 'Image folder missing')
                    errordlg('Please select a vehicle image from the dropdown.', 'Error');
                    return;
                end

                if ~isempty(app.detectionFigureHandle) && isgraphics(app.detectionFigureHandle) && isvalid(app.detectionFigureHandle)
                    close(app.detectionFigureHandle);
                end
                app.detectionFigureHandle = []; 
                
                if ~isempty(app.ocrFigureHandle) && isgraphics(app.ocrFigureHandle) && isvalid(app.ocrFigureHandle)
                    close(app.ocrFigureHandle);
                end
                app.ocrFigureHandle = []; 
    
                vehicleType = app.VehicleTypeDropDown.Value;
                
                [plateRegion, bbox, detFigHandle] = detectLicensePlate(app.originalImage, vehicleType);
                app.detectionFigureHandle = detFigHandle;
                
                if isempty(plateRegion)
                    msgbox('No license plate detected', 'Warning', 'warn');
                    app.plateRegion = []; % Ensure cleared
                    app.plateBbox = [];   % Ensure cleared
                    % Clear previous plate display if any
                    cla(app.SelectedPlateUIAxes);
                    title(app.SelectedPlateUIAxes, 'Selected Plate');
                    % If bounding box was on, refresh original image display without old box
                    if strcmp(app.BoundingBoxSwitch.Value, 'On')
                         imshow(app.originalImage, 'Parent', app.OriginalImageUIAxes);
                         if ~isempty(app.currentFilename)
                            [~, name, ~] = fileparts(app.currentFilename);
                            title(app.OriginalImageUIAxes, ['Selected: ', name]);
                         else
                            title(app.OriginalImageUIAxes, 'Original Image');
                         end
                    end
                    return;
                end
                
                app.plateRegion = plateRegion;
                app.plateBbox = bbox;
                
                imshow(plateRegion, 'Parent', app.SelectedPlateUIAxes);
                title(app.SelectedPlateUIAxes, 'Detected License Plate'); % Update title
                
                if strcmp(app.BoundingBoxSwitch.Value, 'On')
                    showBoundingBox(app);
                end          
                
                [plateText, ocrFigHandleLocal] = recognizePlateWithOCR(app.plateRegion);
                app.ocrFigureHandle = ocrFigHandleLocal;
                                
                state = identifyState(plateText);
                
                app.PlateNumberEditField.Value = plateText;
                app.StateIdentifiedEditField.Value = state;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                % Clear all displays
                cla(app.OriginalImageUIAxes);
                title(app.OriginalImageUIAxes, 'Title');
                cla(app.SelectedPlateUIAxes);
                title(app.SelectedPlateUIAxes, 'Selected Plate');
                
                % Clear results
                app.PlateNumberEditField.Value = '';
                app.StateIdentifiedEditField.Value = '';
                
                % Clear stored variables
                app.originalImage = [];
                app.plateRegion = [];
                app.plateBbox = [];
                app.currentFilename = '';

                if ~isempty(app.detectionFigureHandle) && isgraphics(app.detectionFigureHandle) && isvalid(app.detectionFigureHandle)
                close(app.detectionFigureHandle);
                disp('Closed detection figure.');
                end
                app.detectionFigureHandle = [];

                if ~isempty(app.ocrFigureHandle) && isgraphics(app.ocrFigureHandle) && isvalid(app.ocrFigureHandle)
                close(app.ocrFigureHandle);
                disp('Closed OCR preprocessing figure.');
                end
                app.ocrFigureHandle = [];

                if ~isempty(app.VehicleTypeDropDown.Items)
                    app.VehicleTypeDropDown.Value = app.VehicleTypeDropDown.Items{1}; % 'Select Vehicle Type'
                else
                    app.VehicleTypeDropDown.Value = 'Select Vehicle Type'; 
                end
                
                app.BoundingBoxSwitch.Value = 'On';
        end

        % Value changed function: VehicleTypeDropDown
        function VehicleTypeDropDownValueChanged(app, event)
            cla(app.OriginalImageUIAxes);
            title(app.OriginalImageUIAxes, 'Title'); % Reset title
            cla(app.SelectedPlateUIAxes);
            title(app.SelectedPlateUIAxes, 'Selected Plate'); % Reset title
            app.PlateNumberEditField.Value = '';
            app.StateIdentifiedEditField.Value = '';
            app.originalImage = [];
            app.plateRegion = [];
            app.plateBbox = [];
            app.currentFilename = '';

            selectedType = app.VehicleTypeDropDown.Value;
            
            % Reset VehicleDropDown to its default state
            app.VehicleDropDown.Items = {'Select Vehicle'};
            app.VehicleDropDown.Value = 'Select Vehicle';

            if strcmp(selectedType, 'Select Vehicle Type')
                return;
            end
            
            % Construct the path to the vehicle type's image folder
            pathToMLAPP = fileparts(mfilename('fullpath'));
            % Folder names are expected to be lowercase versions of dropdown items (e.g., 'car', 'motorcycle')
            imageFolder = fullfile(pathToMLAPP, 'assets', 'image', lower(selectedType)); 
            
            if ~isfolder(imageFolder)
                warning('Image folder not found: %s. Please create it or check the path.', imageFolder);
                app.VehicleDropDown.Items = {'Select Vehicle', 'Image folder missing'};
                return;
            end
            
            % List image files in the folder
            supportedExtensions = {'*.jpg', '*.jpeg', '*.png', '*.bmp', '*.gif', '*.tif', '*.tiff'};
            imageFilesList = {};
            
            for i = 1:length(supportedExtensions)
                files = dir(fullfile(imageFolder, supportedExtensions{i}));
                for k = 1:length(files)
                    if ~files(k).isdir
                        imageFilesList{end+1} = files(k).name;
                    end
                end
            end
            
            imageFilesList = unique(imageFilesList, 'stable');

            if isempty(imageFilesList)
                app.VehicleDropDown.Items = {'Select Vehicle', 'No images in folder'};
            else
                numValues = cellfun(@(fName) str2double(regexp(fName, '\d+', 'match', 'once')), imageFilesList);
                [~, sortOrder] = sort(numValues);
                sortedImageFilesList = imageFilesList(sortOrder);
                app.VehicleDropDown.Items = [{'Select Vehicle'}; sortedImageFilesList(:)];
            end
        end

        % Value changed function: VehicleDropDown
        function VehicleDropDownValueChanged(app, event)
            selectedImageName = app.VehicleDropDown.Value;
            selectedVehicleType = app.VehicleTypeDropDown.Value;

            % Clear previous plate-specific data when a new image/selection is made
            cla(app.SelectedPlateUIAxes);
            title(app.SelectedPlateUIAxes, 'Selected Plate');
            app.PlateNumberEditField.Value = '';
            app.StateIdentifiedEditField.Value = '';
            app.plateRegion = [];
            app.plateBbox = [];
            
            % Handle placeholder selections in VehicleDropDown or if VehicleType is not selected
            if strcmp(selectedImageName, 'Select Vehicle') || ...
               strcmp(selectedImageName, 'No images in folder') || ...
               strcmp(selectedImageName, 'Image folder missing') || ...
               strcmp(selectedVehicleType, 'Select Vehicle Type')
                
                cla(app.OriginalImageUIAxes);
                title(app.OriginalImageUIAxes, 'Title'); % Reset title
                app.originalImage = [];
                app.currentFilename = '';
                return;
            end
            
            pathToMLAPP = fileparts(mfilename('fullpath'));
            % Construct full path: assets/image/[lowercase_vehicle_type]/[image_filename_with_extension]
            imageFolderPath = fullfile(pathToMLAPP, 'assets', 'image', lower(selectedVehicleType));
            imagePath = fullfile(imageFolderPath, selectedImageName);
            
            disp(['Attempting to load image: ', imagePath]);
            
            if exist(imagePath, 'file')
                try
                    app.originalImage = imread(imagePath);
                    imshow(app.originalImage, 'Parent', app.OriginalImageUIAxes);
                    app.currentFilename = selectedImageName; % Store filename with extension
                    
                    [~, name, ~] = fileparts(selectedImageName); % Get filename without extension for title
                    title(app.OriginalImageUIAxes, ['Selected: ', name]);

                    % If bounding box switch is on, it will be handled by its own callback
                    % or by showBoundingBox if called after processing.
                    % For now, just display the image. If an old bbox exists for a *previous* image,
                    % it should not be shown here. app.plateBbox was cleared above.
                    
                catch ME
                    cla(app.OriginalImageUIAxes);
                    title(app.OriginalImageUIAxes, ['Error loading: ', selectedImageName]);
                    disp(['Error loading image ''', selectedImageName, ''': ', ME.message]);
                    errordlg(['Could not load image: ', selectedImageName, '. Error: ', ME.message], 'Image Load Error');
                    app.originalImage = [];
                    app.currentFilename = '';
                end
            else
                cla(app.OriginalImageUIAxes);
                title(app.OriginalImageUIAxes, ['Not found: ', selectedImageName]);
                disp(['Image file not found: ', imagePath]);
                errordlg(['Image file not found: ', selectedImageName], 'File Not Found');
                app.originalImage = [];
                app.currentFilename = '';
            end
        end

        % Value changed function: BoundingBoxSwitch
        function BoundingBoxSwitchValueChanged(app, event)
            currentSwitchValue = event.Value;

            % Check if we have an image and bounding box data
            if ~isempty(app.originalImage) && ~isempty(app.plateBbox)
                baseFilename = '';
                if ~isempty(app.currentFilename)
                    baseFilename = regexprep(app.currentFilename, '\..*$', ''); % Get filename without extension
                end

                if strcmp(currentSwitchValue, 'On')
                    % Switch is ON: Show the image with the bounding box
                    app.showBoundingBox();
                else
                    % Switch is OFF: Show the original image without the bounding box
                    imshow(app.originalImage, 'Parent', app.OriginalImageUIAxes);
                    
                    % Restore the title to indicate no bounding box
                    if ~isempty(baseFilename)
                        title(app.OriginalImageUIAxes, ['Selected: ', baseFilename]);
                    else
                        title(app.OriginalImageUIAxes, 'Original Image'); % Fallback title
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.HTML = uihtml(app.Tab);
            app.ExitButton = uibutton(app.Tab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = fullfile(pathToMLAPP, 'assets', 'icon', 'exit.svg');
            app.ExitButton.Text = 'Exit';
            app.LicensePlateRecognitionStateIdentificationSystemLabel = uilabel(app.Tab);
            app.LicensePlateRecognitionStateIdentificationSystemLabel.Text = 'License Plate Recognition & State Identification System';
            app.byOoiWeiHongTP065329LeeJunWeiTP072623Label = uilabel(app.Tab);
            app.byOoiWeiHongTP065329LeeJunWeiTP072623Label.Text = 'by Ooi Wei Hong TP065329  Lee Jun Wei TP072623';
            app.StartButton = uibutton(app.Tab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = fullfile(pathToMLAPP, 'assets', 'icon', 'start.svg');
            app.StartButton.Text = 'Start';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.SelectedPlateUIAxes = uiaxes(app.Tab2);
            title(app.SelectedPlateUIAxes, 'Selected Plate')
            xlabel(app.SelectedPlateUIAxes, 'X')
            ylabel(app.SelectedPlateUIAxes, 'Y')
            zlabel(app.SelectedPlateUIAxes, 'Z')
            app.BackButton = uibutton(app.Tab2, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = fullfile(pathToMLAPP, 'assets', 'icon', 'back.svg');
            app.BackButton.Text = 'Back';
            app.PlateNumberEditField = uieditfield(app.Tab2, 'text');
            app.PlateNumberEditField.Editable = 'off';
            app.StateIdentifiedEditField = uieditfield(app.Tab2, 'text');
            app.StateIdentifiedEditField.Editable = 'off';
            app.OriginalImageLabel = uilabel(app.Tab2);
            app.OriginalImageLabel.Text = 'Original Image';
            app.PlateNumberLabel = uilabel(app.Tab2);
            app.PlateNumberLabel.Text = 'Plate Number:';
            app.StateIdentifiedEditFieldLabel = uilabel(app.Tab2);
            app.StateIdentifiedEditFieldLabel.Text = 'State Identified:';
            app.OriginalImagePanel = uipanel(app.Tab2);
            app.OriginalImagePanel.Title = 'Original Image';
            app.OriginalImageUIAxes = uiaxes(app.OriginalImagePanel);
            title(app.OriginalImageUIAxes, 'Title')
            xlabel(app.OriginalImageUIAxes, 'X')
            ylabel(app.OriginalImageUIAxes, 'Y')
            zlabel(app.OriginalImageUIAxes, 'Z')
            app.ProcessButton = uibutton(app.Tab2, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Icon = fullfile(pathToMLAPP, 'assets', 'icon', 'process.svg');
            app.ProcessButton.Text = 'Process';
            app.ResetButton = uibutton(app.Tab2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'assets', 'icon', 'reset.svg');
            app.ResetButton.Text = 'Reset';
            app.VehicleTypeDropDownLabel = uilabel(app.Tab2);
            app.VehicleTypeDropDownLabel.Text = 'Vehicle Type: ';
            app.VehicleTypeDropDown = uidropdown(app.Tab2);
            app.VehicleTypeDropDown.Items = {'Select Vehicle Type', 'Car', 'Motorcycle', 'Truck', 'Bus'};
            app.VehicleTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleTypeDropDownValueChanged, true);
            app.VehicleTypeDropDown.Value = 'Select Vehicle Type';
            app.VehicleDropDownLabel = uilabel(app.Tab2);
            app.VehicleDropDownLabel.Text = 'Vehicle:';
            app.VehicleDropDown = uidropdown(app.Tab2);
            app.VehicleDropDown.Items = {'Select Vehicle'};
            app.VehicleDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDownValueChanged, true);
            app.VehicleDropDown.Value = 'Select Vehicle';
            app.BoundingBoxSwitchLabel = uilabel(app.Tab2);
            app.BoundingBoxSwitchLabel.Text = 'Bounding Box';
            app.BoundingBoxSwitch = uiswitch(app.Tab2, 'slider');
            app.BoundingBoxSwitch.ValueChangedFcn = createCallbackFcn(app, @BoundingBoxSwitchValueChanged, true);
            app.BoundingBoxSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end